classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                   % UI Figure
        InterfereSignal        matlab.ui.control.UIAxes           % 干扰信号
        ReceivedSignal         matlab.ui.control.UIAxes           % 接收到信号
        ExpectSignal           matlab.ui.control.UIAxes           % 期望信号
        RecoveredSignal        matlab.ui.control.UIAxes           % 自干扰消除前后...
        LabelDropDown          matlab.ui.control.Label            % 期望信号类型
        ExpectTypeDropDown     matlab.ui.control.DropDown         % sin, sq...
        LabelDropDown2         matlab.ui.control.Label            % 参考信号类型
        ReferTypeDropDown      matlab.ui.control.DropDown         % sin, sq...
        LabelNumericEditField  matlab.ui.control.Label            % 期望信号振幅(V)
        ExpectAmp              matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField2 matlab.ui.control.Label            % 参考信号振幅(V)
        ReferAmp               matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField4 matlab.ui.control.Label            % 噪音(dBm)
        Noise                  matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField5 matlab.ui.control.Label            % 干扰信号时延(ns)
        InterfereDelay         matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField6 matlab.ui.control.Label            % 自干扰消除时延
        SelfInterfereDelay     matlab.ui.control.NumericEditField % [-Inf Inf]
        Button                 matlab.ui.control.Button           % 确定
        LabelNumericEditField8 matlab.ui.control.Label            % 系统频率(MHz)
        SystemFreq             matlab.ui.control.NumericEditField % [-Inf Inf]
        ReferSignal            matlab.ui.control.UIAxes           % 参考信号
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button button pushed function
        function ButtonButtonPushed(app)
            fRF = app.SystemFreq.Value;
            Vs = app.ExpectAmp.Value;
            Vr = app.ReferAmp.Value;
            sType = app.ExpectTypeDropDown.Value;
            rType = app.ReferTypeDropDown.Value;
            noiseDB = app.Noise.Value;
            delayT = app.InterfereDelay.Value;
            selfDelayT = app.SelfInterfereDelay.Value;
            
            timeFreq = 1000000;  % the number of cols in every time unit
            T = 0 : 1/timeFreq : 1;
            
            % draw expect signal
            switch(sType)
                case 'sin'
                    sSignal = SinWave(Vs, fRF, T);
                case 'square'
                    sSignal = SquareWave(Vs, fRF, T);
                case 'triangle'
                    sSignal = TriWave(Vs, fRF, T);
                case 'noise'
                    sSignal = NoiseWave(Vs, timeFreq);
            end
            DrawAxes(app.ExpectSignal, T, sSignal, Vs);
            
            % draw refer signal
            switch(rType)
                case 'sin'
                    rSignal = SinWave(Vr, fRF, T);
                case 'square'
                    rSignal = SquareWave(Vr, fRF, T);
                case 'triangle'
                    rSignal = TriWave(Vr, fRF, T);
                case 'noise'
                    rSignal = NoiseWave(Vr, timeFreq);
            end
            DrawAxes(app.ReferSignal, T, rSignal, Vr);
            
            % draw interfere signal
            iSignal = InterferenceWave(rSignal, delayT, noiseDB, timeFreq);
            DrawAxes(app.InterfereSignal, T, iSignal, max(iSignal));
            
            % draw received signal
            received = iSignal + sSignal;
            DrawAxes(app.ReceivedSignal, T, received, max(received));
            
            % use algorithm to calculate recovered signal
            tau = 2*pi;
            fc1 = 2000;
            fc2 = 3000;
            E1 = 1; 
            E2 = 1;
            
            recovered = SIC(T, received, rSignal, E1, fc1, E2, fc2, delayT);
            recoveredF = timeToLogFreq(recovered);
            receivedF = timeToLogFreq(received);
            
            % draw recovered signal
            DrawFreqAxes(app.RecoveredSignal, receivedF, recoveredF, fRF);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.InterfereSignal = uiaxes(app.UIFigure);
            title(app.InterfereSignal, '干扰信号');
            xlabel(app.InterfereSignal, 'X(μs)');
            ylabel(app.InterfereSignal, 'Y(V)');
            app.ReceivedSignal = uiaxes(app.UIFigure);
            title(app.ReceivedSignal, '接收到信号');
            xlabel(app.ReceivedSignal, 'X(μs)');
            ylabel(app.ReceivedSignal, 'Y(V)');
            app.ExpectSignal = uiaxes(app.UIFigure);
            title(app.ExpectSignal, '期望信号');
            xlabel(app.ExpectSignal, 'X(μs)');
            ylabel(app.ExpectSignal, 'Y(V)');
            app.RecoveredSignal = uiaxes(app.UIFigure);
            title(app.RecoveredSignal, '自干扰消除前后频域对比图');
            xlabel(app.RecoveredSignal, 'X(MHz)');
            ylabel(app.RecoveredSignal, 'Y(dbm)');
            app.LabelDropDown = uilabel(app.UIFigure);
            app.LabelDropDown.Text = '期望信号类型';
            app.ExpectTypeDropDown = uidropdown(app.UIFigure);
            app.ExpectTypeDropDown.Items = {'sin', 'square', 'triangle', 'noise'};
            app.ExpectTypeDropDown.Value = 'sin';
            app.LabelDropDown2 = uilabel(app.UIFigure);
            app.LabelDropDown2.Text = '参考信号类型';
            app.ReferTypeDropDown = uidropdown(app.UIFigure);
            app.ReferTypeDropDown.Items = {'sin', 'square', 'triangle', 'noise'};
            app.ReferTypeDropDown.Value = 'sin';
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = '期望信号振幅(V)';
            app.ExpectAmp = uieditfield(app.UIFigure, 'numeric');
            app.ExpectAmp.Value = 0.1;
            app.LabelNumericEditField2 = uilabel(app.UIFigure);
            app.LabelNumericEditField2.Text = '参考信号振幅(V)';
            app.ReferAmp = uieditfield(app.UIFigure, 'numeric');
            app.ReferAmp.Value = 0.4;
            app.LabelNumericEditField4 = uilabel(app.UIFigure);
            app.LabelNumericEditField4.Text = '噪音(dBm)';
            app.Noise = uieditfield(app.UIFigure, 'numeric');
            app.Noise.Value = -20;
            app.LabelNumericEditField5 = uilabel(app.UIFigure);
            app.LabelNumericEditField5.Text = '干扰信号时延(ns)';
            app.InterfereDelay = uieditfield(app.UIFigure, 'numeric');
            app.LabelNumericEditField6 = uilabel(app.UIFigure);
            app.LabelNumericEditField6.Text = '自干扰消除时延';
            app.SelfInterfereDelay = uieditfield(app.UIFigure, 'numeric');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonButtonPushed);
            app.Button.Text = '确定';
            app.LabelNumericEditField8 = uilabel(app.UIFigure);
            app.LabelNumericEditField8.Text = '系统频率(MHz)';
            app.SystemFreq = uieditfield(app.UIFigure, 'numeric');
            app.SystemFreq.Value = 5000;
            app.ReferSignal = uiaxes(app.UIFigure);
            title(app.ReferSignal, '参考信号');
            xlabel(app.ReferSignal, 'X(μs)');
            ylabel(app.ReferSignal, 'Y(V)');
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end